classdef etc_render_fsbrain_tms_nav < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Move3DobjectUIFigure     matlab.ui.Figure
        TMScoilPanel             matlab.ui.container.Panel
        CoordinatesLabel         matlab.ui.control.Label
        navoptionsDropDown       matlab.ui.control.DropDown
        navoptionsDropDownLabel  matlab.ui.control.Label
        CheckBox                 matlab.ui.control.CheckBox
        GoButton                 matlab.ui.control.Button
        ScalpPanel               matlab.ui.container.Panel
        ShowscalpCheckBox        matlab.ui.control.CheckBox
        loadscalpButton          matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: loadscalpButton
        function loadscalpButtonPushed(app, event)
            global etc_render_fsbrain;

            fname=sprintf('%s/%s/bem/%s',getenv('SUBJECTS_DIR'),etc_render_fsbrain.subject, 'outer_skin.surf');

            %[filename, pathname, filterindex] = uigetfile({fname,'*.*','scalp surface (*.surf)'}, 'Pick the scalp surface','outer_skin.surf');
            if(exist(fname))
                [filename, pathname, filterindex] = uigetfile(fname, 'Pick the scalp surface','outer_skin.surf');
            else
                [filename, pathname, filterindex] = uigetfile({'*.*','scalp surface (*.surf)'}, 'Pick the scalp surface','outer_skin.surf');

            end;
            if(filename==0) return; end;
              
            [vv,ff]=read_surf(sprintf('%s/%s',pathname,filename));

            %geometrical information about scalp
            TR = triangulation(ff+1,vv);
            surf_center= incenter(TR);
            surf_norm = faceNormal(TR);


            cc=get(gca,'colororder');
            h=patch('vertices',vv,'faces',ff+1,'edgecolor','none','facecolor',cc(1,:),'facealpha',0.2); %show scalp

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Move3DobjectUIFigure = uifigure('Visible', 'off');
            app.Move3DobjectUIFigure.Name = 'MATLAB App';
            app.ScalpPanel = uipanel(app.Move3DobjectUIFigure);
            app.ScalpPanel.Title = 'Scalp';
            app.loadscalpButton = uibutton(app.ScalpPanel, 'push');
            app.loadscalpButton.ButtonPushedFcn = createCallbackFcn(app, @loadscalpButtonPushed, true);
            app.loadscalpButton.Text = 'load scalp';
            app.ShowscalpCheckBox = uicheckbox(app.ScalpPanel);
            app.ShowscalpCheckBox.Text = 'Show scalp';
            app.TMScoilPanel = uipanel(app.Move3DobjectUIFigure);
            app.TMScoilPanel.Title = 'TMS coil';
            app.GoButton = uibutton(app.TMScoilPanel, 'push');
            app.GoButton.Text = 'Go';
            app.CheckBox = uicheckbox(app.TMScoilPanel);
            app.CheckBox.Text = '';
            app.navoptionsDropDownLabel = uilabel(app.TMScoilPanel);
            app.navoptionsDropDownLabel.Text = 'nav options';
            app.navoptionsDropDown = uidropdown(app.TMScoilPanel);
            app.CoordinatesLabel = uilabel(app.TMScoilPanel);
            app.CoordinatesLabel.Text = 'Coordinates';
            % Show the figure after all components are created
            app.Move3DobjectUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = etc_render_fsbrain_tms_nav

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Move3DobjectUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Move3DobjectUIFigure)
        end
    end
end